classdef AMIC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AMICUIFigure                    matlab.ui.Figure
        SeleccioneunaopcinListBoxLabel  matlab.ui.control.Label
        SeleccioneunaopcinListBox       matlab.ui.control.ListBox
        TablerodecontrolPanel           matlab.ui.container.Panel
        TextArea                        matlab.ui.control.TextArea
        ObtenerelclculoButton           matlab.ui.control.StateButton
        GraficarelmodeloButton          matlab.ui.control.StateButton
        DatosPanel                      matlab.ui.container.Panel
        UITableData                     matlab.ui.control.Table
        ModelosAproximantesPanel        matlab.ui.container.Panel
        UITableModels                   matlab.ui.control.Table
        ErrorPanel                      matlab.ui.container.Panel
        UITableError                    matlab.ui.control.Table
        MejoraproximacinEditFieldLabel  matlab.ui.control.Label
        MejoraproximacinEditField       matlab.ui.control.EditField
    end

    % Properties that holds the app's state
    properties (Access = private)
        Process = 0 % 1: Proceso activo, no se puede ingrear valores ; 0: Proceso inactivo, se pueden ingresar valores
        Points      % Puntos a considerar para la aproximación
        Decimals    % Número entero que representa la cantidad de decimales a considerar
        Method      % Método de aproximación que esta siendo procesado actualmente
        Formula     % Contiene el string de la función aproximante que se encuentra en tratamiento 
        S           % Contiene la matriz de la sumatoria de la aproximación actual
        Error       % Contiene el error obtenido en la aproximación actual
        FitObject   % Contiene el objeto en cuestión cuyo plot(app.fitObject,app.Points(:,1),app.Points(:,2)) realiza el gráfico
        N = 0       % Contiene la cantidad de modelos aplicados desde que comenzo el proceso
        SMODS       % Contiene los nombres de las columnas de los Modelos aproximantes
        SERRS       % Contiene los nombres de las columnas de los Errores
    end

    methods (Access = private)
    
        % Handle user's initial data entry
        function outDataEntry = dataEntry(app)
            if app.Process ~= 1
                bool = 1;
                % Ingresar puntos del plano
                while bool == 1
                    prompt = {'Ingrese una serie de puntos de la forma [Xn f(Xn); Xn+1 f(Xn+1);...]:'};
                    answer = inputdlg(prompt,'Ingresar Datos',1,{'[1 10;2 5;3 2;4 1]'});
                    if isempty(answer)
                        outDataEntry = 0;
                        return;
                    end
                    points = str2num(answer{1});
                    if isempty(points)
                        uiwait(msgbox('Ingrese los puntos en el formato correcto', 'Error'));
                    else
                        bool = 0;   
                    end
                end
                app.Points = points;
                bool = 1;
                % Ingresar cantidad de decimales
                while bool == 1
                    prompt = {'Ingrese la cantidad de decimales con los que trabajará (en formato [n]):'};
                    answer = inputdlg(prompt,'Ingresar Datos',1,{'[3]'});
                    if isempty(answer)
                        outDataEntry = 0;
                        return;
                    end
                    decimals = str2num(answer{1});
                    if ( isnumeric(decimals) == 1 ) && (decimals >= 0 )
                        bool = 0;
                    else
                        uiwait(msgbox('Ingrese un número entero positivo para determinar la cantidad de decimales', 'Error'));       
                    end
                end
                app.Decimals = decimals;
            end
            % Seleccionar método aproximante
            prompt = {'1. Recta de mínimos cuadrados: y = ax + b',...
                      '2. Parábola de mínimos cuadrados: y = ax^2 + bx + c',...
                      '3. Aproximación Exponencial: y = be^(ax)',...
                      '4. Aproximación Potencial: y = bx^a',...
                      '5. Aproximación Hipérbola: y = ax/(x+b)'};
            [Selection,ok] = listdlg('PromptString','Seleccione un método aproximante',...
                                    'SelectionMode', 'single',...
                                    'ListString',prompt,...
                                    'Name', 'Métodos aproximantes',...
                                    'OKString', 'Continue',...
                                    'ListSize',[330 230]);
            if not(ok == 1)
               outDataEntry = 0;
               return;
            else
               app.Method = Selection;
               outDataEntry = 1;
           end 
        end
        
        % Do calculations
        function outDoMath = doMath(app)
            app.S = [];
            sumx2 = 0;
            sumx3 = 0;
            sumx4 = 0;
            sumxy = 0;
            sumx2y = 0;
            sumlnx = 0;
            sum2lnx = 0;
            sumy = 0;
            sumlny = 0;
            sumlnyx= 0;
            sumlnylnx = 0;
            sum1divX= 0;
            [r, c] = size(app.Points);
            sumx = 0;
            sumy = 0;
            for i=1:r
                sumx = app.Points(i,1) + sumx;
                sumy = app.Points(i,2) + sumy;
            end    
            switch app.Method
                case 1
                    % Caso de la Recta de mínimos cuadrados
                    [fitobject,gof,output] = fit(app.Points(:,1),app.Points(:,2),'poly1');
                    formatSpec = '\n Y = %sX %c %s';
                    C = coeffvalues(fitobject);
                    C = round(C,app.Decimals);
                    if C(:,2) > 0.
                        sign = '+';
                    else
                        sign = '';
                    end
                    s1 = num2str(C(:,1));
                    s2 = num2str(C(:,2));
                    str = sprintf(formatSpec,s1,sign,s2);
                    %% Algoritmo para la tabla de sumatorias:
                    for i=1:r
                        app.S(i,1) = (app.Points(i,1))^2;
                        sumx2 = app.S(i,1) + sumx2;
                        app.S(i,2) = app.Points(i,1)*app.Points(i,2);
                        sumxy = app.S(i,2) + sumxy;
                    end
                    row = [ sumx sumy sumx2 sumxy ];
                    app.S = [ app.Points app.S ];
                    app.S(r+1,:) = row;
                case 2    
                    % Caso de la Parábola de mínimos cuadrados
                    [fitobject,gof,output] = fit(app.Points(:,1),app.Points(:,2),'poly2');
                    formatSpec = '\n Y = %sX^2 %c %sX %s %s';
                    C = coeffvalues(fitobject);
                    C = round(C,app.Decimals);
                    if C(:,2) > 0.
                        sign1 = '+';
                    else
                        sign1 = '';
                    end
                    if C(:,3) > 0.
                        sign2 = '+';
                    else
                        sign2 = '';
                    end   
                    s1 = num2str(C(:,1));
                    s2 = num2str(C(:,2));
                    s3 = num2str(C(:,3));
                    str = sprintf(formatSpec,s1,sign1,s2,sign2,s3);
                    %% Algoritmo para la tabla de sumatorias:
                    for i=1:r
                        app.S(i,1) = app.Points(i,1)^2;
                        sumx2 = app.S(i,1) + sumx2;
                        app.S(i,2) = app.Points(i,1)^3;
                        sumx3 = app.S(i,2) + sumx3;
                        app.S(i,3) = app.Points(i,1)^4;
                        sumx4 = app.S(i,3) + sumx4;
                        app.S(i,4) = app.Points(i,1)^2*app.Points(i,2);
                        sumx2y = app.S(i,4) + sumx2y;
                    end
                    row = [ sumx sumy sumx2 sumx3 sumx4 sumx2y ];
                    app.S = [ app.Points app.S ];
                    app.S(r+1,:) = row;
                case 3
                    % Caso de la Aproximación Exponencial
                    [fitobject,gof,output] = fit(app.Points(:,1),app.Points(:,2),'exp1');
                    formatSpec = '\n Y = %se^(%s%sX)'; 
                    C = coeffvalues(fitobject);
                    C = round(C,app.Decimals);
                    if C(:,2) > 0.
                        sign = '+';
                    else
                        sign = '';
                    end
                    s1 = num2str(C(:,1));
                    s2 = num2str(C(:,2));
                    str = sprintf(formatSpec,s1,sign,s2);
                    %% Algoritmo para la tabla de sumatorias:
                    for i=1:r
                        app.S(i,1) = log(app.Points(i,2));
                        sumlny = app.S(i,1) + sumlny;
                        app.S(i,2) = app.Points(i,1)^2;
                        sumx2 = app.S(i,2) + sumx2;
                        app.S(i,3) = app.S(i,1) * app.Points(i,1);
                        sumlnyx = app.S(i,3) + sumlnyx;
                    end
                    row = [ sumx sumlny sumx2 sumlnyx ];
                    app.S = [ app.Points(:,1) app.S ];
                    app.S(r+1,:) = row;
                case 4
                    % Caso de la Aproximación Potencial
                    [fitobject,gof,output] = fit(app.Points(:,1),app.Points(:,2),'power1');
                    formatSpec = '\n Y = %sX^%s'; 
                    C = coeffvalues(fitobject);
                    C = round(C,app.Decimals);
                    s1 = num2str(C(:,1));
                    s2 = num2str(C(:,2));
                    str = sprintf(formatSpec,s1,s2);
                     %% Algoritmo para la tabla de sumatorias:
                    for i=1:r
                        app.S(i,1) = log(app.Points(i,1));
                        sumlnx = app.S(i,1) + sumlnx;
                        app.S(i,2) = log(app.Points(i,2));
                        sumlny = app.S(i,2) + sumlny;
                        app.S(i,3) = app.S(i,1)^2;
                        sum2lnx = app.S(i,3) + sum2lnx; 
                        app.S(i,4) = app.S(i,1) * app.S(i,2) ;
                        sumlnylnx = app.S(i,4) + sumlnylnx;
                    end
                    row = [ sumlnx sumlny sum2lnx sumlnylnx ];
                    app.S(r+1,:) = row;
                case 5
                    % Caso de la Aproximación Hipérbola
                    ft  = fittype('a*x/(b+x)','dependent',{'y'},'independent',{'x'},'coefficients',{'a','b'});
                    [fitobject,gof,output] = fit(app.Points(:,1),app.Points(:,2),ft);
                    formatSpec = '\n Y = %sX/(%s+X)'; 
                    C = coeffvalues(fitobject);
                    C = round(C,app.Decimals);
                    s1 = num2str(C(:,1));
                    s2 = num2str(C(:,2));
                    str = sprintf(formatSpec,s1,s2);
                    %% Algoritmo para la tabla de sumatorias:
                    for i=1:r
                        app.S(i,1) = 1 / app.Points(i,1);
                        sum1divX = app.S(i,1) + sum1divX;
                        app.S(i,2) = app.Points(i,2);
                        sumy = app.S(i,2) + sumy;
                        app.S(i,3) = app.S(i,1)^2;
                        sumx2 = app.S(i,3) + sumx2;
                        app.S(i,4) = app.S(i,1)*app.S(i,2);
                        sumxy = app.S(i,4) + sumxy; 
                    end
                    row = [ sum1divX sumy sumx2 sumxy ];
                    app.S(r+1,:) = row;
            end
            % Save Properties
            app.Formula   = str;
            app.Error     = round(gof.sse,app.Decimals);
            app.FitObject = fitobject;
            app.N = app.N + 1;
             
        end
        
        % Fill program's screen with data
        function outFillScreen = fillScreen(app)
            app.TextArea.Value = app.Formula;
            app.ObtenerelclculoButton.Enable = 'on';
            app.GraficarelmodeloButton.Enable = 'on';
            rows = size(app.Points,1);
            for i=1:rows
                R(i,:) = i;
            end
            app.UITableData.Data   = [ R app.Points ];
            if isempty(app.UITableModels.Data) || isempty(app.UITableError.Data)
                app.UITableModels.Data = [ { app.Formula } ];
                app.UITableError.Data  = [ { app.Error } ];
            else
                app.UITableModels.Data = [ app.UITableModels.Data app.Formula ];  
                app.UITableError.Data  = [ app.UITableError.Data app.Error  ];
            end
            for i=1:app.N
                app.SMODS{1,i} = strcat('Mod. ', num2str(i));
                app.SERRS{1,i} = strcat('Error Mod. ', num2str(i));
            end
            set(app.UITableModels, 'ColumnName', app.SMODS );
            set(app.UITableError, 'ColumnName', app.SERRS );
            
        end
        
        % Get the best aproach
        function outCompare = compare(app)
            ant = 999999;
            for i=1:app.N
               act = app.UITableError.Data{1,i};
               if act < ant
                   ant = act;
                   outCompare = i;
               end
            end
            app.MejoraproximacinEditField.Value = strcat(app.UITableModels.Data{1,outCompare}, ', E=', num2str(app.UITableError.Data{1,outCompare}));
        end    
        
        % End current process
        function outEndProcess = endProcess(app)
            app.Process = 0; 
            app.Points = [];     
            app.Decimals = [];         
            app.Method = [];           
            app.Formula = []; 
            app.S = [];
            app.Error = [];            
            app.FitObject = [];        
            app.N = 0;      
            app.SMODS = [];            
            app.SERRS = [];
            app.TextArea.Value = ' ';
            app.UITableData.Data = []; 
            app.UITableModels.Data = []; 
            app.UITableError.Data = []; 
            set(app.UITableData, 'ColumnName', [] );
            set(app.UITableModels, 'ColumnName', [] );
            set(app.UITableError, 'ColumnName', [] );
            app.MejoraproximacinEditField.Value = ' ';
        end
        
    end


    methods (Access = private)

        % Value changed function: SeleccioneunaopcinListBox
        function SeleccioneunaopcinListBoxValueChanged(app, event)
            value = app.SeleccioneunaopcinListBox.Value;
            value = value(1:1);
            switch value
                case 'A' % A. Ingresar Datos
                    res = dataEntry(app);
                    if res == 1
                        app.Process = 1;
                        doMath(app);
                        fillScreen(app);
                    end
                case 'B' % B. Comparar Aproximaciones
                    if app.N < 2.
                        errordlg('Debe ingresar, por lo menos, 2 modelos aproximantes para realizar la comparativa','Error');
                    else    
                        compare(app);
                    end    
                case 'C' %. Finalizar 
                    endProcess(app)
            end
          
        end

        % Value changed function: GraficarelmodeloButton
        function GraficarelmodeloButtonValueChanged(app, event)
            app.GraficarelmodeloButton.Value = 0;
            plot(app.FitObject,app.Points(:,1),app.Points(:,2));
           
        end

        % Value changed function: ObtenerelclculoButton
        function ObtenerelclculoButtonValueChanged(app, event)
            app.ObtenerelclculoButton.Value = 0;
        switch app.Method
            case 1
                uitable('Data', app.S,'ColumnName', { 'Xi', 'Yi', '(Xi)^2', 'Xi*Yi' }, 'ColumnWidth', {65} );
            case 2
                uitable('Data', app.S,'ColumnName', { 'Xi', 'Yi', '(Xi)^2', '(Xi)^3', '(Xi)^4', 'Xi*Yi' }, 'ColumnWidth', {65} );
            case 3
                uitable('Data', app.S,'ColumnName', { 'Xi', 'Yi = ln(yi)', '(Xi)^2', 'Yi*Xi' }, 'ColumnWidth', {65} );
            case 4 
                uitable('Data', app.S,'ColumnName', { 'Xi = ln(xi)', 'Yi = ln(yi)', '(Xi)^2', 'Yi*Xi' }, 'ColumnWidth', {65} );
            case 5
                uitable('Data', app.S,'ColumnName', { 'Xi = 1/xi', 'Yi', '(Xi)^2', 'Yi*Xi' }, 'ColumnWidth', {65} );
        end        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AMICUIFigure = uifigure;
            app.AMICUIFigure.Name = 'AMIC';
            app.SeleccioneunaopcinListBoxLabel = uilabel(app.AMICUIFigure);
            app.SeleccioneunaopcinListBoxLabel.Text = 'Seleccione una opción:';
            app.SeleccioneunaopcinListBox = uilistbox(app.AMICUIFigure);
            app.SeleccioneunaopcinListBox.Items = {'A. Ingresar Datos', 'B. Comparar Aproximaciones', 'C. Finalizar', '...'};
            app.SeleccioneunaopcinListBox.ValueChangedFcn = createCallbackFcn(app, @SeleccioneunaopcinListBoxValueChanged, true);
            app.SeleccioneunaopcinListBox.Value = 'B. Comparar Aproximaciones';
            app.TablerodecontrolPanel = uipanel(app.AMICUIFigure);
            app.TablerodecontrolPanel.Title = 'Tablero de control';
            app.TextArea = uitextarea(app.TablerodecontrolPanel);
            app.TextArea.Editable = 'off';
            app.ObtenerelclculoButton = uibutton(app.TablerodecontrolPanel, 'state');
            app.ObtenerelclculoButton.ValueChangedFcn = createCallbackFcn(app, @ObtenerelclculoButtonValueChanged, true);
            app.ObtenerelclculoButton.Enable = 'off';
            app.ObtenerelclculoButton.Text = 'Obtener el cálculo';
            app.GraficarelmodeloButton = uibutton(app.TablerodecontrolPanel, 'state');
            app.GraficarelmodeloButton.ValueChangedFcn = createCallbackFcn(app, @GraficarelmodeloButtonValueChanged, true);
            app.GraficarelmodeloButton.Enable = 'off';
            app.GraficarelmodeloButton.Text = 'Graficar el modelo';
            app.DatosPanel = uipanel(app.AMICUIFigure);
            app.DatosPanel.Title = 'Datos';
            app.UITableData = uitable(app.DatosPanel);
            app.UITableData.ColumnName = {'I'; 'Xi'; 'Yi'};
            app.UITableData.RowName = {};
            app.ModelosAproximantesPanel = uipanel(app.AMICUIFigure);
            app.ModelosAproximantesPanel.Title = 'Modelos Aproximantes';
            app.UITableModels = uitable(app.ModelosAproximantesPanel);
            app.UITableModels.ColumnName = {'Mod..'};
            app.UITableModels.RowName = {};
            app.ErrorPanel = uipanel(app.AMICUIFigure);
            app.ErrorPanel.Title = 'Error';
            app.UITableError = uitable(app.ErrorPanel);
            app.UITableError.ColumnName = {'Error Mod..'};
            app.UITableError.RowName = {};
            app.MejoraproximacinEditFieldLabel = uilabel(app.AMICUIFigure);
            app.MejoraproximacinEditFieldLabel.Text = 'Mejor aproximación';
            app.MejoraproximacinEditField = uieditfield(app.AMICUIFigure, 'text');
            app.MejoraproximacinEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AMIC

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AMICUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AMICUIFigure)
        end
    end
end